classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        HMinSliderLabel  matlab.ui.control.Label
        HMinSlider       matlab.ui.control.Slider
        ResetButton      matlab.ui.control.Button
        HMaxSliderLabel  matlab.ui.control.Label
        HMaxSlider       matlab.ui.control.Slider
        SMinSliderLabel  matlab.ui.control.Label
        SMinSlider       matlab.ui.control.Slider
        SMaxSliderLabel  matlab.ui.control.Label
        SMaxSlider       matlab.ui.control.Slider
        VMinSliderLabel  matlab.ui.control.Label
        VMinSlider       matlab.ui.control.Slider
        VMaxSliderLabel  matlab.ui.control.Label
        VMaxSlider       matlab.ui.control.Slider
        TitleLabel       matlab.ui.control.Label
        HMinLabel        matlab.ui.control.Label
        HMaxLabel        matlab.ui.control.Label
        SMinLabel        matlab.ui.control.Label
        SMaxLabel        matlab.ui.control.Label
        VMinLabel        matlab.ui.control.Label
        VMaxLabel        matlab.ui.control.Label
    end


    methods (Access = private)
    
        function ImgOut = ExtractYellow(~, ImgIn, HMin,HMax, SMin,SMax, VMin,VMax)
            ImgRGB = ImgIn;
            ImgHSV = rgb2hsv(ImgRGB);
            
            % 提取图像中黄色部分，得到只有黄色部分的图
            ImgNewRGB = 0*ones(size(ImgRGB));         % 创建一个白色图像，将特定颜色提取到此处
            ImgNewHSV = rgb2hsv(ImgNewRGB);             % 将白色图像也转换至hsv色彩空间
            
            [row, col] = ind2sub( size(ImgHSV),...      % 找出图像中黄色的像素
                find( ImgHSV(:,:,1)>=HMin & ImgHSV(:,:,1)<=HMax...
                    & ImgHSV(:,:,2)>=SMin & ImgHSV(:,:,2)<=SMax...
                    & ImgHSV(:,:,3)>=VMin & ImgHSV(:,:,3)<=VMax ) );
            
            for i = 1 : length(row)                     % 将图像中的黄色像素复制到刚才新建的白色图像中
                ImgNewHSV(row(i),col(i),:) = ImgHSV(row(i),col(i),:);
            end
            
            ImgNewRGB = hsv2rgb(ImgNewHSV);     % 将提取出来的黄色，转化至rgb空间，进行展示
            
            ImgOut = ImgNewRGB;
        end

        function results = ReadImg(app)
            filename = 'xihongshi1.jpeg';
            results = imread(filename);
        end
        
    end


    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            ImgIn = ReadImg(app);
            imshow(ImgIn,'Parent',app.UIAxes);
        end

        % Value changed function: HMinSlider
        function HMinSliderValueChanged(app, event)

            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);
        end

        % Value changed function: HMaxSlider
        function HMaxSliderValueChanged(app, event)
            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);
            
        end

        % Value changed function: SMinSlider
        function SMinSliderValueChanged(app, event)
            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);
            
        end

        % Value changed function: SMaxSlider
        function SMaxSliderValueChanged(app, event)
            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);

        end

        % Value changed function: VMinSlider
        function VMinSliderValueChanged(app, event)
            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);
            
        end

        % Value changed function: VMaxSlider
        function VMaxSliderValueChanged(app, event)
            HMin = app.HMinSlider.Value;
            app.HMinLabel.Text = num2str(HMin);
            HMax = app.HMaxSlider.Value;
            app.HMaxLabel.Text = num2str(HMax);
            SMin = app.SMinSlider.Value;
            app.SMinLabel.Text = num2str(SMin);
            SMax = app.SMaxSlider.Value;
            app.SMaxLabel.Text = num2str(SMax);
            VMin = app.VMinSlider.Value;
            app.VMinLabel.Text = num2str(VMin);
            VMax = app.VMaxSlider.Value;
            app.VMaxLabel.Text = num2str(VMax);
            
            ImgIn = ReadImg(app);
            
            ImgOut = ExtractYellow(app, ImgIn,HMin,HMax, SMin,SMax, VMin,VMax);
            
            imshow(ImgOut,'Parent',app.UIAxes);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HMinSliderLabel = uilabel(app.UIFigure);
            app.HMinSliderLabel.Text = 'HMin';
            app.HMinSlider = uislider(app.UIFigure);
            app.HMinSlider.Limits = [0 1];
            app.HMinSlider.ValueChangedFcn = createCallbackFcn(app, @HMinSliderValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HMaxSliderLabel = uilabel(app.UIFigure);
            app.HMaxSliderLabel.Text = 'HMax';
            app.HMaxSlider = uislider(app.UIFigure);
            app.HMaxSlider.Limits = [0 1];
            app.HMaxSlider.ValueChangedFcn = createCallbackFcn(app, @HMaxSliderValueChanged, true);
            app.HMaxSlider.Value = 1;
            app.SMinSliderLabel = uilabel(app.UIFigure);
            app.SMinSliderLabel.Text = 'SMin';
            app.SMinSlider = uislider(app.UIFigure);
            app.SMinSlider.Limits = [0 1];
            app.SMinSlider.ValueChangedFcn = createCallbackFcn(app, @SMinSliderValueChanged, true);
            app.SMaxSliderLabel = uilabel(app.UIFigure);
            app.SMaxSliderLabel.Text = 'SMax';
            app.SMaxSlider = uislider(app.UIFigure);
            app.SMaxSlider.Limits = [0 1];
            app.SMaxSlider.ValueChangedFcn = createCallbackFcn(app, @SMaxSliderValueChanged, true);
            app.SMaxSlider.Value = 1;
            app.VMinSliderLabel = uilabel(app.UIFigure);
            app.VMinSliderLabel.Text = 'VMin';
            app.VMinSlider = uislider(app.UIFigure);
            app.VMinSlider.Limits = [0 1];
            app.VMinSlider.ValueChangedFcn = createCallbackFcn(app, @VMinSliderValueChanged, true);
            app.VMaxSliderLabel = uilabel(app.UIFigure);
            app.VMaxSliderLabel.Text = 'VMax';
            app.VMaxSlider = uislider(app.UIFigure);
            app.VMaxSlider.Limits = [0 1];
            app.VMaxSlider.ValueChangedFcn = createCallbackFcn(app, @VMaxSliderValueChanged, true);
            app.VMaxSlider.Value = 1;
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'HSV空间图片分离器';
            app.HMinLabel = uilabel(app.UIFigure);
            app.HMinLabel.Text = 'HMin';
            app.HMaxLabel = uilabel(app.UIFigure);
            app.HMaxLabel.Text = 'HMax';
            app.SMinLabel = uilabel(app.UIFigure);
            app.SMinLabel.Text = 'SMin';
            app.SMaxLabel = uilabel(app.UIFigure);
            app.SMaxLabel.Text = 'SMax';
            app.VMinLabel = uilabel(app.UIFigure);
            app.VMinLabel.Text = 'VMin';
            app.VMaxLabel = uilabel(app.UIFigure);
            app.VMaxLabel.Text = 'VMax';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end